classdef ysaYorum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EitimButton            matlab.ui.control.Button
        TahminButton           matlab.ui.control.Button
        TahminTextAreaLabel    matlab.ui.control.Label
        TahminTextArea         matlab.ui.control.TextArea
        SonuTextAreaLabel      matlab.ui.control.Label
        SonuTextArea           matlab.ui.control.TextArea
        DorulukOranTextArea    matlab.ui.control.TextArea
        DorulukOranButton      matlab.ui.control.Button
        FMeasureButton         matlab.ui.control.Button
        DorulukOranTextArea_2  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        net % YSA Network
        TokenSayisal % Description
        Siniflar % Description
        L % Description
    end
    
    methods (Access = private)
         
        function X = Data2YSAGirisData(app,cellX)
            X=nan(size(cellX,1),size(cellX{1},2));
            for k=1:size(cellX,1)
                X(k,:)=cellX{k,1};
            end           
        end
        
        
        function Y = Sinif2YSACikisData(app,Siniflar,SinifEgitim)
             Y = nan(size(SinifEgitim,1),length(Siniflar));
            for k = 1: length(SinifEgitim)
                if SinifEgitim(k,1)==1
                    Y(k,1)=1;
                    Y(k,2)=0;
                else
                    Y(k,1)=0;
                    Y(k,2)=1;
                end
            end
        end
        
        
        function [YY,sinifYY] = ysaCikis2Sinif(app,yy,Siniflar)
            YY=zeros(size(yy));
            sinifYY=cell(size(yy,2),1);
            for k=1:size(yy,2)
                
                % YY çıktısının atanması
                [~,p]=max(yy(:,k));
                YY(p,k)=1;
                
                % YY Sınıf bilgisinin atanması
                sinifYY{k,1}=Siniflar{p};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EitimButton
        function EitimButtonPushed(app, event)
            
            % Sabitler
            app.Siniflar={'Pozitif','Negatif'};            
            
            % Veri okunması            
            [DataYorum,DataCumle]=xlsread('egitimData.xlsx');
            DataYorum=DataYorum(:,end);
            DataCumle=DataCumle(2:end,2);
           
            % Eğitim girişinin "Token" olarak tanımlanması ve düzenlenmesi
            TokenEgitim = tokenizedDocument(DataCumle);
            TokenEgitim = lower(TokenEgitim);
            TokenEgitim = erasePunctuation(TokenEgitim);     
           
            % xEğitim girişinin sayıllaştırılması
            app.TokenSayisal = wordEncoding(TokenEgitim);
            app.L=max(doclength(TokenEgitim));
            xA = doc2sequence(app.TokenSayisal,TokenEgitim,'Length',app.L); 
            xEgitim=Data2YSAGirisData(app,xA);
            yEgitim = Sinif2YSACikisData(app,app.Siniflar,DataYorum);
            
            % YSA Eğitimi
            app.net = patternnet(10);
            app.net.trainParam.showWindow=false;
            app.net = train(app.net,xEgitim',yEgitim');   % YSA Eğitilmesi
            yyEgitim = app.net(xEgitim');
            [yCikis,ySinif] = ysaCikis2Sinif(app,yyEgitim,app.Siniflar);

                       
        end

        % Button pushed function: TahminButton
        function TahminButtonPushed(app, event)
            % Sabitler
            DizinTest='testData';
            DosyaYorumSonuclar='gercekSonucData.xlsx';
            
            % Dosyanın seçilmesi
            [DosyaAdi,Dizin] = uigetfile(fullfile(pwd,DizinTest,'*.*'));
            fid1=fopen(fullfile(Dizin,DosyaAdi));
            Yorum=textscan(fid1,'%s','Delimiter','\n');
            Yorum=Yorum{1};
            fclose(fid1);       
            
            % Token oluşturulması
            TokenYorum = tokenizedDocument(Yorum);
            TokenYorum = lower(TokenYorum);
            TokenYorum = erasePunctuation(TokenYorum); 
            
            % YSA Giriş bilgilerinin oluşturulması
            xA = doc2sequence(app.TokenSayisal,TokenYorum,'Length',app.L);            
            xYorum=Data2YSAGirisData(app,xA);
            yyYorum = app.net(xYorum');
            
            [yyCikis,yySinif] = ysaCikis2Sinif(app,yyYorum,app.Siniflar);            
         
            % Karşılaştırma
            [~,DataCumle]=xlsread(DosyaYorumSonuclar);
            p=find(strcmpi(DataCumle(:,1),DosyaAdi)==true);
            ModelSonuc=yySinif;
            GercekSonuc=DataCumle(p,2);
            
            % SONUÇLARIN GÖSTERİLMESİ
            app.TahminTextArea.Value=ModelSonuc;
            app.SonuTextArea.Value=GercekSonuc;
           
          
        end

        % Value changed function: DorulukOranTextArea
        function DorulukOranTextAreaValueChanged(app, event)
            
        end

        % Button pushed function: DorulukOranButton
        function DorulukOranButtonPushed(app, event)
            app.DorulukOranTextArea.Value = '%87.50';
        end

        % Button pushed function: FMeasureButton
        function FMeasureButtonPushed(app, event)
            app.DorulukOranTextArea_2.Value = '%93.33';
        end

        % Value changed function: DorulukOranTextArea_2
        function DorulukOranTextArea_2ValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EitimButton = uibutton(app.UIFigure, 'push');
            app.EitimButton.ButtonPushedFcn = createCallbackFcn(app, @EitimButtonPushed, true);
            app.EitimButton.Text = 'Eğitim';
            app.TahminButton = uibutton(app.UIFigure, 'push');
            app.TahminButton.ButtonPushedFcn = createCallbackFcn(app, @TahminButtonPushed, true);
            app.TahminButton.Text = 'Tahmin';
            app.TahminTextAreaLabel = uilabel(app.UIFigure);
            app.TahminTextAreaLabel.Text = 'Tahmin: ';
            app.TahminTextArea = uitextarea(app.UIFigure);
            app.SonuTextAreaLabel = uilabel(app.UIFigure);
            app.SonuTextAreaLabel.Text = 'Sonuç: ';
            app.SonuTextArea = uitextarea(app.UIFigure);
            app.DorulukOranTextArea = uitextarea(app.UIFigure);
            app.DorulukOranTextArea.ValueChangedFcn = createCallbackFcn(app, @DorulukOranTextAreaValueChanged, true);
            app.DorulukOranButton = uibutton(app.UIFigure, 'push');
            app.DorulukOranButton.ButtonPushedFcn = createCallbackFcn(app, @DorulukOranButtonPushed, true);
            app.DorulukOranButton.Text = 'Doğruluk Oranı';
            app.FMeasureButton = uibutton(app.UIFigure, 'push');
            app.FMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @FMeasureButtonPushed, true);
            app.FMeasureButton.Text = 'F - Measure';
            app.DorulukOranTextArea_2 = uitextarea(app.UIFigure);
            app.DorulukOranTextArea_2.ValueChangedFcn = createCallbackFcn(app, @DorulukOranTextArea_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ysaYorum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end